classdef Neuron_Spatial_Explorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Neuron_Spatial_ExplorerUIFigure  matlab.ui.Figure
        ImagesEditFieldLabel    matlab.ui.control.Label
        Images                  matlab.ui.control.EditField
        ROIsEditFieldLabel      matlab.ui.control.Label
        ROIs                    matlab.ui.control.EditField
        SelectedLabel           matlab.ui.control.Label
        DisplayROIs             matlab.ui.control.TextArea
        ImgFn                   matlab.ui.control.Button
        ROIFn                   matlab.ui.control.Button
        UpdateButton            matlab.ui.control.Button
        Image_ROI_OverlayPanel  matlab.ui.container.Panel
    end

    methods (Access = private)

        % Button pushed function: ImgFn
        function ImgFnButtonPushed(app, event)
            [strImgFn,strImgDir] = uigetfile('*.tif','Select the Image File');
            if(~isequal(strImgFn,0))
                app.Images.Value= [strImgDir strImgFn];
            end
        end

        % Button pushed function: ROIFn
        function ROIFnButtonPushed(app, event)
            [strROIFn,strROIDir] = uigetfile('*.tif','Select the ROIs File');
            if(~isequal(strROIFn,0))
                app.ROIs.Value= [strROIDir strROIFn];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Neuron_Spatial_ExplorerUIFigure = uifigure;
            app.Neuron_Spatial_ExplorerUIFigure.Name = 'Neuron_Spatial_Explorer';
            app.ImagesEditFieldLabel = uilabel(app.Neuron_Spatial_ExplorerUIFigure);
            app.ImagesEditFieldLabel.Text = 'Images:';
            app.Images = uieditfield(app.Neuron_Spatial_ExplorerUIFigure, 'text');
            app.ROIsEditFieldLabel = uilabel(app.Neuron_Spatial_ExplorerUIFigure);
            app.ROIsEditFieldLabel.Text = 'ROIs:';
            app.ROIs = uieditfield(app.Neuron_Spatial_ExplorerUIFigure, 'text');
            app.SelectedLabel = uilabel(app.Neuron_Spatial_ExplorerUIFigure);
            app.SelectedLabel.Text = 'Selected:';
            app.DisplayROIs = uitextarea(app.Neuron_Spatial_ExplorerUIFigure);
            app.ImgFn = uibutton(app.Neuron_Spatial_ExplorerUIFigure, 'push');
            app.ImgFn.ButtonPushedFcn = createCallbackFcn(app, @ImgFnButtonPushed, true);
            app.ImgFn.Text = '...';
            app.ROIFn = uibutton(app.Neuron_Spatial_ExplorerUIFigure, 'push');
            app.ROIFn.ButtonPushedFcn = createCallbackFcn(app, @ROIFnButtonPushed, true);
            app.ROIFn.Text = '...';
            app.UpdateButton = uibutton(app.Neuron_Spatial_ExplorerUIFigure, 'push');
            app.UpdateButton.Text = 'Update';
            app.Image_ROI_OverlayPanel = uipanel(app.Neuron_Spatial_ExplorerUIFigure);
            app.Image_ROI_OverlayPanel.Title = 'Image_ROI_Overlay';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Neuron_Spatial_Explorer

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Neuron_Spatial_ExplorerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Neuron_Spatial_ExplorerUIFigure)
        end
    end
end